classdef global_peaks_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SaveCurrentParameterDataButton  matlab.ui.control.Button
        SmthDegreeSpinner               matlab.ui.control.Spinner
        SmthDegreeSpinnerLabel          matlab.ui.control.Label
        SmthSpanSpinner                 matlab.ui.control.Spinner
        SmthSpanLabel                   matlab.ui.control.Label
        OfPeaksSpinner                  matlab.ui.control.Spinner
        OfPeaksSpinnerLabel             matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        MinDistEditField                matlab.ui.control.NumericEditField
        MinDistEditFieldLabel           matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        LoadedListBox                   matlab.ui.control.ListBox
        LoadedListBoxLabel              matlab.ui.control.Label
        LeftButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        ofChunksEditField               matlab.ui.control.NumericEditField
        ofChunksEditFieldLabel          matlab.ui.control.Label
        RandomChunksButton              matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        SplitEditField                  matlab.ui.control.NumericEditField
        SplitEditFieldLabel             matlab.ui.control.Label
        PerformPeakFindingButton        matlab.ui.control.Button
        ParameterDropDown               matlab.ui.control.DropDown
        ParameterDropDownLabel          matlab.ui.control.Label
        ShowRawSwitch                   matlab.ui.control.Switch
        ShowRawRedLabel                 matlab.ui.control.Label
        ShowPeaksSwitch                 matlab.ui.control.Switch
        ShowPeaksSwitchLabel            matlab.ui.control.Label
        YcoordinateEditField            matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel       matlab.ui.control.Label
        XcoordinateEditField            matlab.ui.control.NumericEditField
        XcoordinateEditFieldLabel       matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PeakFindingTab                  matlab.ui.container.Tab
        SpectrumAxes                    matlab.ui.control.UIAxes
        DisplayAxes                     matlab.ui.control.UIAxes
        PixelDetailsTab                 matlab.ui.container.Tab
        FileDataTable                   matlab.ui.control.Table
        PixelValueTable                 matlab.ui.control.Table
        PixelValueAxes                  matlab.ui.control.UIAxes
        SlopeSuppressionTab             matlab.ui.container.Tab
        CalculateSlopesButton           matlab.ui.control.Button
        SavePlotDataButton              matlab.ui.control.Button
        FilestoAlignListBox             matlab.ui.control.ListBox
        FilestoAlignListBoxLabel        matlab.ui.control.Label
        AlignImagesButton               matlab.ui.control.Button
        PlottedValueDropDown            matlab.ui.control.DropDown
        PlottedValueDropDownLabel       matlab.ui.control.Label
        SlopeFileDataTable              matlab.ui.control.Table
        SlopeAxes                       matlab.ui.control.UIAxes
        SlopeMapAxes                    matlab.ui.control.UIAxes
        FunPlotsTab                     matlab.ui.container.Tab
        VerticalSpectrumAxes            matlab.ui.control.UIAxes
        HorizontalSpectrumAxes          matlab.ui.control.UIAxes
        Axes3D                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        currentRandomIndex = 1
        randomCoords = [[1, 1]]
        xLine
        yLine
        xLine2
        yLine2
        parameter_labels = ["D_{111} (Hz)", "E_{111} (Hz)", "Width (σ)", ... 
                        "111 Width Deviation (σ)", "111 Contrast (%)", ... 
                        "111 Contrast Deviation", "D_{non111} (Hz)", ... 
                        "E_{non111}", "Non-111 Width (σ)", ...
                        "Non-111 Width Deviation", "Non-111 Contrast (%)", ...
                        "Non-111 Contrast Deviation", 'Baseline (Hz)', ...
                        'Tries', 'Left Peak', 'Right Peak'];
        all_gWides = struct("signal", {}, "SweepParam", {})
        all_parameter_dfs = {}
        all_settings = struct("split", {}, "tolerance", {}, ...
            "num_max_peaks", {}, "peak_perc_threshold", {}, ...
            "diff_peak_distance", {}, "smooth_span", {}, ...
            "smooth_degree", {}, "lower", {}, "upper", {})
        x_coords
        slope_df
    end
    
    properties (Access = public)
        xcoord % Description
        ycoord
    end
    
    methods (Access = private)
        function plot_data(app)
           global gWide
           cla(app.DisplayAxes);
           data=squeeze(gWide.signal(:,:,2))';
           im = imagesc(app.DisplayAxes, data); % Display the slice
           im.HitTest = 'off';
           colorbar(app.DisplayAxes);
           clim(app.DisplayAxes, 'auto')
           lims = clim(app.DisplayAxes);
           app.LowerLimitEditField.Value = lims(1);
           app.UpperLimitEditField.Value = lims(2);
           xlim(app.DisplayAxes,[1 size(data,2)])
           ylim(app.DisplayAxes,[1 size(data,1)])
           pbaspect(app.DisplayAxes,[1 size(data,1)/size(data,2) 1]);
           title(app.DisplayAxes, "Initial Signal Value")
            
           app.XcoordinateEditField.LowerLimitInclusive = 1;
           app.YcoordinateEditField.LowerLimitInclusive = 1;
           app.XcoordinateEditField.UpperLimitInclusive = size(data, 2);
           app.YcoordinateEditField.UpperLimitInclusive = size(data, 1);
           plot_signal(app);
        end

        function plot_signal(app)
            global gWide
            if ~isempty(app.xLine)
                delete(app.xLine)
            end
            if ~isempty(app.yLine)
                delete(app.yLine)
            end
            if ~isempty(app.xLine2)
                delete(app.xLine2)
            end
            if ~isempty(app.yLine2)
                delete(app.yLine2)
            end
           x = app.XcoordinateEditField.Value;
           y = app.YcoordinateEditField.Value;
           
           data=squeeze(gWide.signal(:,:,2))';
           if x > size(data, 2)
               x = size(data, 2) - 1;
           end
           if y > size(data, 2)
               y = size(data, 2) - 1;
           end

           if strcmp(app.ParameterDropDown.Value, "Tries")
               app.xLine = xline(app.DisplayAxes, x, 'Color','c');
               app.yLine = yline(app.DisplayAxes, y, 'Color', 'c');
           else
               app.xLine = xline(app.DisplayAxes, x, 'Color','r');
               app.yLine = yline(app.DisplayAxes, y, 'Color', 'r');
           end
           app.xLine2 = xline(app.SlopeMapAxes, x, 'Color','r');
           app.yLine2 = yline(app.SlopeMapAxes, y, 'Color', 'r');

           split = app.SplitEditField.Value;
           tolerance = app.ToleranceEditField.Value;
           num_max_peaks = app.OfPeaksSpinner.Value;
           peak_perc_threshold = app.ThresholdEditField.Value;
           diff_peak_distance = app.MinDistEditField.Value;
           smooth_span = app.SmthSpanSpinner.Value;
           smooth_degree = app.SmthDegreeSpinner.Value;
           data = gWide.signal;
           freq = gWide.SweepParam;
           [vals, locs, widths, proms, threshold, signal, raw] = find_peaks_at_point(y, x, data, freq, false, ...
               num_max_peaks, peak_perc_threshold, diff_peak_distance, smooth_span, smooth_degree);
           cla(app.SpectrumAxes);
           hold(app.SpectrumAxes, 'on');
           if strcmp(app.ShowRawSwitch.Value, "On")
               plot(app.SpectrumAxes, gWide.SweepParam, raw, 'Color', 'r')
           end
           plot(app.SpectrumAxes, gWide.SweepParam, signal, 'Color', 'b')
           [sorted_locs, sorted_vals, ~, index_pairs] = ...
            partner_peak(locs, split, tolerance, signal, threshold, ...
            freq, vals, widths, proms, false, raw);

           if strcmp(app.ShowPeaksSwitch.Value, "On")
               yline(app.SpectrumAxes, threshold, 'Color', 'r') 
               naive_baseline = mean(squeeze(gWide.signal(x, y, 2)));
               % yline(app.SpectrumAxes, naive_baseline, 'Color', 'c')
               
               plot(app.SpectrumAxes, locs, vals, 'rv', 'MarkerFaceColor', 'k');
               colors = ['r', 'g', 'b', 'c', 'm', 'y'];

                for i = 1:size(index_pairs, 1) % For each pair
                    if i <= ceil(size(index_pairs, 1) / 2)
                        peak = sorted_locs(index_pairs(i, 1)); % Get the first peak and second peak
                        peak_partner = sorted_locs(index_pairs(i, 2));
                        init_height = sorted_vals(index_pairs(i, 1));
                        second_height = sorted_vals(index_pairs(i, 2));
                        color = colors(mod(i - 1, 6) + 1); % Give them a color
                        plot(app.SpectrumAxes, peak, init_height, 'rv', 'MarkerFaceColor', color)
                        plot(app.SpectrumAxes, peak_partner, second_height, 'rv', 'MarkerFaceColor', color)
                        text(app.SpectrumAxes, peak_partner + 0.02e9, second_height, num2str(i))
                        text(app.SpectrumAxes, peak + 0.02e9,init_height,num2str(i))

                        xline(app.SpectrumAxes, split, 'LineWidth', 2)
                        if i == 1
                            center = 2 * split - peak;
                            xline(app.SpectrumAxes, peak)
                            xline(app.SpectrumAxes, center)
                            xregion(app.SpectrumAxes, center - tolerance, center + tolerance, 'FaceColor', color)
                            % center = 2 * split - peak_partner;
                            % xline(center)
                            % xregion(center - tolerance, center + tolerance, 'FaceColor', color)
                        end
                    end
                end

           end
        
           hold(app.SpectrumAxes, 'off');
           table_signal(app)
           spectrum(app);

        end

        function table_signal(app)
            global parameters_dataframe
            x = app.XcoordinateEditField.Value;
            y = app.YcoordinateEditField.Value;
            index = app.ParameterDropDown.ValueIndex;
            if index > length(app.parameter_labels)
                index = 1;
            end
            if ~isempty(app.all_parameter_dfs)
                data = squeeze(parameters_dataframe(y, x, :));
                app.PixelValueTable.Data = [app.parameter_labels', data];
    
                all_data = zeros(size(app.all_parameter_dfs));
                for i=1:numel(app.all_parameter_dfs)
                    curr_param = app.all_parameter_dfs{i};
                    if ~isempty(curr_param)
                        all_data(i) = squeeze(curr_param(y, x, index));
                    end
                end

                if ~isempty(all_data)
                    file_names = 1:size(all_data, 2);
                    sample_x_coords = 1:size(all_data, 2);
                    app.x_coords = sample_x_coords';
                    if ~isempty(app.SlopeFileDataTable.Data)
                        if size(app.x_coords, 1) == size(app.SlopeFileDataTable.Data(:, 3), 1)
                            app.x_coords = app.SlopeFileDataTable.Data(:, 3);
                        end
                    end
                    
                    app.FileDataTable.Data = [file_names', all_data', app.x_coords];
                    app.SlopeFileDataTable.Data = [file_names', all_data', app.x_coords];
                    cla(app.PixelValueAxes)
                    hold(app.PixelValueAxes, 'on');
                    plot(app.PixelValueAxes, app.x_coords, all_data);
                    plot(app.PixelValueAxes, app.x_coords, all_data, 'rv');
                    hold(app.PixelValueAxes, 'off');

                    cla(app.SlopeAxes)
                    hold(app.SlopeAxes, 'on');
                    plot(app.SlopeAxes, app.x_coords, all_data);
                    plot(app.SlopeAxes, app.x_coords, all_data, 'rv');
                    if ~isempty(app.slope_df)
                        slope = squeeze(app.slope_df(x, y, 1));
                        intercept = squeeze(app.slope_df(x, y, 3));
                        disp(intercept + slope * app.x_coords)
                        disp(all_data)
                        plot(app.SlopeAxes, app.x_coords, intercept + slope * app.x_coords)
                    end
                    hold(app.SlopeAxes, 'off');
                end
            end
        end

        function surface3d(app)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
            if i > numel(app.parameter_labels)
                i = 1;
            end
            data = parameters_dataframe(:, :, i);
            data(data == 0) = NaN;
            surf(app.Axes3D, data);
        end

        function spectrum(app)
            global parameters_dataframe
            if isempty(parameters_dataframe)
                return;
            end
            cla(app.VerticalSpectrumAxes);
            x = app.XcoordinateEditField.Value;
            xData1 = parameters_dataframe(:, x, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.VerticalSpectrumAxes, 'on')
            plot(app.VerticalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(:, x, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.VerticalSpectrumAxes, xData2, 'Color','r');
            hold(app.VerticalSpectrumAxes, 'off')

            cla(app.HorizontalSpectrumAxes);
            y = app.YcoordinateEditField.Value;
            xData1 = parameters_dataframe(y, :, 15);
            xData1(xData1 == 0) = NaN; % don't show it if it's 0
            hold(app.HorizontalSpectrumAxes, 'on')
            plot(app.HorizontalSpectrumAxes, xData1, 'Color', 'b');
            xData2 = parameters_dataframe(y, :, 16);
            xData2(xData2 == 0) = NaN; % don't show it if it's 0
            plot(app.HorizontalSpectrumAxes, xData2, 'Color','r');
            hold(app.HorizontalSpectrumAxes, 'off')
        end
    end
    
    methods (Access = public)
        
        function handleClick(app)
            point = app.DisplayAxes.CurrentPoint;
            app.XcoordinateEditField.Value = point(1, 1);
            app.YcoordinateEditField.Value = point(1, 2);
            plot_signal(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           global gWide
           [baseFileNames, folders] = uigetfile('.mat','Choose File', 'MultiSelect','on');
           figure(app.UIFigure)
            
           if isequal(baseFileNames, 0)
               % User clicked cancel
               return;
           end
            
           % Ensure baseFileNames is a cell array for consistent processing
           if ischar(baseFileNames)
               baseFileNames = {baseFileNames};
           end

           f = waitbar(0, "Loading...");
           for i = 1:numel(baseFileNames)
               baseFileName = baseFileNames{i};
               % folder = folders(i, :);
               fullFileName = fullfile(folders, baseFileName);
               loadedData = load(fullFileName);
               app.all_gWides(end + 1).signal = loadedData.gWide.signal;
               app.all_gWides(end).SweepParam = loadedData.gWide.SweepParam;
               gWide = app.all_gWides(end);
               app.LoadedListBox.Items(end + 1) = {baseFileName};
               app.LoadedListBox.Value = baseFileName;
               waitbar(i / numel(baseFileNames), f, "Loading...")
           end
           waitbar(i / numel(baseFileNames), f, "Completed!")
           close(f)
           plot_data(app);
           app.ParameterDropDown.Enable = 'off';
           app.ParameterDropDownLabel.Enable = 'off';
           figure(app.UIFigure)
        end

        % Value changed function: XcoordinateEditField
        function XcoordinateEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: YcoordinateEditField
        function YcoordinateEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ShowPeaksSwitch
        function ShowPeaksSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ShowRawSwitch
        function ShowRawSwitchValueChanged(app, event)
            plot_signal(app);
        end

        % Button pushed function: RandomChunksButton
        function RandomChunksButtonPushed(app, event)
            global gWide
            app.currentRandomIndex = 1;
            data=squeeze(gWide.signal(:,:,2))';
            xsize = size(data, 2);
            ysize = size(data, 1);
            chunk_size = xsize / app.ofChunksEditField.Value;
            app.randomCoords = [];
            for x = randi([0, chunk_size - 1], 1, ceil(xsize / chunk_size)) + (1:chunk_size:xsize)
                for y = randi([0, chunk_size - 1], 1, ceil(ysize / chunk_size)) + (1:chunk_size:ysize)
                    app.randomCoords(end + 1, :) = [x, y];
                end
            end

            app.XcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.YcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 2);
            plot_signal(app);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.currentRandomIndex = app.currentRandomIndex + 1;
            if app.currentRandomIndex > length(app.randomCoords)
                app.currentRandomIndex = 1;
            end
            app.XcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.YcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 2);
            plot_signal(app);
        end

        % Button pushed function: PerformPeakFindingButton
        function PerformPeakFindingButtonPushed(app, event)
            global gWide parameters_dataframe
            data=squeeze(gWide.signal(:,:,2))';
            split = app.SplitEditField.Value;
            tolerance = app.ToleranceEditField.Value;
            xsize = size(data, 2);
            ysize = size(data, 1);
            num_params = numel(app.parameter_labels);
            parameters_dataframe = zeros(ysize, xsize, num_params);
            app.PerformPeakFindingButton.Enable = 'off';
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDownLabel.Enable = 'off';
            drawnow
            num_max_peaks = app.OfPeaksSpinner.Value;
            peak_perc_threshold = app.ThresholdEditField.Value;
            diff_peak_distance = app.MinDistEditField.Value;
            smooth_span = app.SmthSpanSpinner.Value;
            smooth_degree = app.SmthDegreeSpinner.Value;

            f = waitbar(0, "Starting Computation");
            data = gWide.signal;
            freq = gWide.SweepParam;
            for x = 1:xsize
                waitbar(x / xsize, f, "Finding for Column " + x)
                for y = 1:ysize
                    [vals, locs, widths, proms, threshold, signal, raw] = find_peaks_at_point(y, x, data, freq, ...
                        false, num_max_peaks, peak_perc_threshold, diff_peak_distance, ...
                        smooth_span, smooth_degree);
                    [sorted_locs, sorted_vals, sorted_widths, pairs, error_coeff] = partner_peak(locs, split, tolerance, signal, ...
                        threshold, freq, vals, widths, proms, false, raw);
                    baseline = find_baseline(y, x, data, freq);
                    seed = peaks_to_seed(sorted_locs, sorted_vals, sorted_widths, pairs, baseline, error_coeff);
                    parameters_dataframe(y, x, :) = seed;
                end
            end
            waitbar(1, f, "Finding Finished")
            close(f)
            app.all_parameter_dfs{app.LoadedListBox.ValueIndex} = parameters_dataframe;
            app.PerformPeakFindingButton.Enable = 'on';
            app.ParameterDropDown.Enable = 'on';
            app.ParameterDropDownLabel.Enable = 'on';
            drawnow
            
            i = app.ParameterDropDown.ValueIndex;
            if i > length(app.parameter_labels)
                i = 1;
                app.ParameterDropDown.ValueIndex = 1;
            end
            data = parameters_dataframe(:, :, i);
            [zeroRows, zeroCols] = find(data == 0);
            data(data == 0) = NaN;
            im = imagesc(app.DisplayAxes, data); % Display the slice
            im.HitTest = 'off';
            clim(app.DisplayAxes, 'auto');
            colorbar(app.DisplayAxes); % Add a colorbar to each subplot
            lims = clim(app.DisplayAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            hold(app.DisplayAxes, 'on');
            zeroVals = plot(app.DisplayAxes, zeroCols, zeroRows, 'rs', 'MarkerSize', 1, 'MarkerFaceColor', 'r');
            if ~isempty(zeroVals)
                zeroVals.HitTest = 'off';
            end
            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')

            surface3d(app)
            spectrum(app)

            plot_signal(app)
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.currentRandomIndex = app.currentRandomIndex - 1;
            if app.currentRandomIndex < 1
                app.currentRandomIndex = length(app.randomCoords);
            end
            app.XcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 1);
            app.YcoordinateEditField.Value = app.randomCoords(app.currentRandomIndex, 2);
            plot_signal(app);
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            global parameters_dataframe
            i = app.ParameterDropDown.ValueIndex;
  
            if i > length(app.parameter_labels)
                plot_data(app)
                return;
            end
            
            data = parameters_dataframe(:, :, i);
            [zeroRows, zeroCols] = find(data == 0);
            data(data == 0) = NaN;
            % cla(app.DisplayAxes)
            im = imagesc(app.DisplayAxes, data); % Display the slice
            im.HitTest = 'off';
            colorbar(app.DisplayAxes); % Add a colorbar to each subplot
            clim(app.DisplayAxes, "auto");
            lims = clim(app.DisplayAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            hold(app.DisplayAxes, 'on');
            zeros = plot(app.DisplayAxes, zeroCols, zeroRows, 'rs', 'MarkerSize', 1, 'MarkerFaceColor', 'r');
            if ~isempty(zeros)
               zeros.HitTest = 'off';
            end
            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')

            surface3d(app);
            plot_signal(app)
        end

        % Value changed function: LoadedListBox
        function LoadedListBoxValueChanged(app, event)
            global gWide parameters_dataframe
            index = app.LoadedListBox.ValueIndex;
            gWide = app.all_gWides(index);
            
            if length(app.all_parameter_dfs) >= index
                if isempty(app.all_parameter_dfs{index})
                    plot_data(app)
                    plot_signal(app)
                    app.PerformPeakFindingButton.Enable = 'on';
                    app.ParameterDropDown.Enable = 'off';
                    app.ParameterDropDownLabel.Enable = 'off';
                    return;
                end
                parameters_dataframe = app.all_parameter_dfs{index};
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'on';
                app.ParameterDropDownLabel.Enable = 'on';
            else
                plot_data(app)
                plot_signal(app)
                app.PerformPeakFindingButton.Enable = 'on';
                app.ParameterDropDown.Enable = 'off';
                app.ParameterDropDownLabel.Enable = 'off';
                return;
            end
            
            i = app.ParameterDropDown.ValueIndex;

            if i > length(app.parameter_labels)
                plot_data(app)
                plot_signal(app)
                return;
            end
            
            data = parameters_dataframe(:, :, i);
            [zeroRows, zeroCols] = find(data == 0);
            data(data == 0) = NaN;
            cla(app.DisplayAxes)
            im = imagesc(app.DisplayAxes, data); % Display the slice
            im.HitTest = 'off';
            colorbar(app.DisplayAxes); % Add a colorbar to each subplot
            clim(app.DisplayAxes, "auto");
            lims = clim(app.DisplayAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.DisplayAxes,[1 size(data,2)])
            ylim(app.DisplayAxes,[1 size(data,1)])
            pbaspect(app.DisplayAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.DisplayAxes, 'on');
            zeros = plot(app.DisplayAxes, zeroCols, zeroRows, 'rs', 'MarkerSize', 1, 'MarkerFaceColor', 'r');
            if ~isempty(zeros)
                zeros.HitTest = 'off';
            end
            title(app.DisplayAxes, [app.parameter_labels(i)]); % Add a title to each subplot
            hold(app.DisplayAxes, 'off')
            plot_signal(app)
        end

        % Value changed function: SplitEditField
        function SplitEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            plot_signal(app)
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
        clim(app.DisplayAxes, [value, value2]);
        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            value = app.LowerLimitEditField.Value;
            value2 = app.UpperLimitEditField.Value;
            clim(app.DisplayAxes,[value, value2]);
        end

        % Value changed function: SmthDegreeSpinner
        function SmthDegreeSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: SmthSpanSpinner
        function SmthSpanSpinnerValueChanged(app, event)
            plot_signal(app);            
        end

        % Value changed function: OfPeaksSpinner
        function OfPeaksSpinnerValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Value changed function: MinDistEditField
        function MinDistEditFieldValueChanged(app, event)
            plot_signal(app);
        end

        % Button down function: DisplayAxes
        function handle(app, event)
            point = app.DisplayAxes.CurrentPoint;
            app.XcoordinateEditField.Value = point(1, 1);
            app.YcoordinateEditField.Value = point(1, 2);
            plot_signal(app);
        end

        % Button pushed function: SaveCurrentParameterDataButton
        function SaveCurrentParameterDataButtonPushed(app, event)
            global gWide parameters_dataframe
            if isempty(app.all_gWides) % nothing plotted
                uialert(app.UIFigure, "No figure plotted", "Error");
                return;
            end
            [file, location] = uiputfile('data.mat');
            if location ~= 0
                index = app.ParameterDropDown.ValueIndex;
                file_path = fullfile(location, file); 
                if index > numel(app.parameter_labels)
                    data = squeeze(gWide.signal(:,:,2))';
                    % file_path = fullfile(selected_folder, "data.mat");
                    save(file_path, 'data');
                else
                    if isempty(parameters_dataframe) || isempty(app.all_parameter_dfs)
                        uialert(app.UIFigure, "Error getting parameters, have you performed peak finding?", "Error");                        
                    else
                        uialert(app.UIFigure, "File successfully saved to directory.", ...
                            "Success", 'Icon', 'success');
                        data = squeeze(parameters_dataframe(:, :, index));
                        % file_path = fullfile(selected_folder, "data.mat");
                        save(file_path, 'data');
                    end
                end
            end
        end

        % Button down function: SlopeMapAxes
        function SlopeMapAxesButtonDown(app, event)
            point = app.SlopeMapAxes.CurrentPoint;
            app.XcoordinateEditField.Value = point(1, 1);
            app.YcoordinateEditField.Value = point(1, 2);
            plot_signal(app);
        end

        % Cell edit callback: SlopeFileDataTable
        function SlopeFileDataTableCellEdit(app, event)
            % indices = event.Indices;
            % newData = event.NewData;
            plot_signal(app); 
        end

        % Button pushed function: CalculateSlopesButton
        function CalculateSlopesButtonPushed(app, event)
            if isempty(app.all_parameter_dfs) || isempty(app.all_gWides)    
                uialert(app.UIFigure, "Need to perform peak finding on loaded files first.", "Error")
                return;
            end
            index = app.ParameterDropDown.ValueIndex;
            test = app.all_gWides(1).signal;
            xsize = size(test, 1);
            ysize = size(test, 2);
            all_data = zeros(xsize, ysize, size(app.all_parameter_dfs, 2));
            for i=1:numel(app.all_parameter_dfs)
                curr_param = app.all_parameter_dfs{i};
                if ~isempty(curr_param)
                    all_data(:, :, i) = curr_param(:, :, index);
                end
            end

            app.slope_df = zeros(xsize, ysize, 3);
            f = waitbar(0, "Starting slopes calculation");
            for x = 1:xsize
                waitbar(x / xsize, f, "Calculating for column " + x);
                for y = 1:ysize
                    coefficients = polyfit(app.x_coords, all_data(x, y, :), 1);
                    slope = coefficients(1);
                    intercept = coefficients(2);
                    
                    % Calculate r^2
                    predicted_y = polyval(coefficients, x);
                    mean_y = mean(y);
                    SSres = sum((y - predicted_y).^2);
                    SStot = SSres + sum((y - mean_y).^2);
                    r_squared = 1 - SSres/SStot;
                    app.slope_df(x, y, :) = [slope, intercept, r_squared];
                end
            end
            waitbar(1, f, "Completed!");
            close(f);

            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            [zeroRows, zeroCols] = find(data == 0);
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            im = imagesc(app.SlopeMapAxes, data); % Display the slice
            im.HitTest = 'off';
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            zeroVals = plot(app.SlopeMapAxes, zeroCols, zeroRows, 'rs', 'MarkerSize', 1, 'MarkerFaceColor', 'r');
            if ~isempty(zeroVals)
                zeroVals.HitTest = 'off';
            end
            i = app.ParameterDropDown.ValueIndex;
            title(app.SlopeMapAxes, [app.parameter_labels(i), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')
        end

        % Value changed function: PlottedValueDropDown
        function PlottedValueDropDownValueChanged(app, event)
            plot_index = app.PlottedValueDropDown.ValueIndex;
            data = app.slope_df(:, :, plot_index);
            [zeroRows, zeroCols] = find(data == 0);
            data(data == 0) = NaN;
            cla(app.SlopeMapAxes)
            im = imagesc(app.SlopeMapAxes, data); % Display the slice
            im.HitTest = 'off';
            if plot_index == 2
                colormap(app.SlopeMapAxes, 'jet');
            end
            colorbar(app.SlopeMapAxes); % Add a colorbar to each subplot
            clim(app.SlopeMapAxes, "auto");
            lims = clim(app.SlopeMapAxes);
            app.LowerLimitEditField.Value = lims(1);
            app.UpperLimitEditField.Value = lims(2);
            xlim(app.SlopeMapAxes,[1 size(data,2)])
            ylim(app.SlopeMapAxes,[1 size(data,1)])
            pbaspect(app.SlopeMapAxes,[1 size(data,1)/size(data,2) 1]);
            hold(app.SlopeMapAxes, 'on');
            zeroVals = plot(app.SlopeMapAxes, zeroCols, zeroRows, 'rs', 'MarkerSize', 1, 'MarkerFaceColor', 'r');
            if ~isempty(zeroVals)
                zeroVals.HitTest = 'off';
            end
            i = app.ParameterDropDown.ValueIndex;
            title(app.SlopeMapAxes, [app.parameter_labels(i), " ", app.PlottedValueDropDown.Value]); % Add a title to each subplot
            hold(app.SlopeMapAxes, 'off')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {657, 657};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = ',Peak Finding Debugger';
            app.XcoordinateEditFieldLabel = uilabel(app.LeftPanel);
            app.XcoordinateEditFieldLabel.Text = 'X-coordinate';
            app.XcoordinateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XcoordinateEditField.Limits = [1 256];
            app.XcoordinateEditField.RoundFractionalValues = 'on';
            app.XcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @XcoordinateEditFieldValueChanged, true);
            app.XcoordinateEditField.Value = 1;
            app.YcoordinateEditFieldLabel = uilabel(app.LeftPanel);
            app.YcoordinateEditFieldLabel.Text = 'Y-coordinate';
            app.YcoordinateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YcoordinateEditField.Limits = [1 256];
            app.YcoordinateEditField.RoundFractionalValues = 'on';
            app.YcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @YcoordinateEditFieldValueChanged, true);
            app.YcoordinateEditField.Value = 1;
            app.ShowPeaksSwitchLabel = uilabel(app.LeftPanel);
            app.ShowPeaksSwitchLabel.Text = 'Show Peaks';
            app.ShowPeaksSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowPeaksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPeaksSwitchValueChanged, true);
            app.ShowPeaksSwitch.Value = 'On';
            app.ShowRawRedLabel = uilabel(app.LeftPanel);
            app.ShowRawRedLabel.Text = 'Show Raw (Red)';
            app.ShowRawSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowRawSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowRawSwitchValueChanged, true);
            app.ParameterDropDownLabel = uilabel(app.LeftPanel);
            app.ParameterDropDownLabel.Enable = 'off';
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.LeftPanel);
            app.ParameterDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', 'Tries', 'Left Peak', 'Right Peak', 'Default'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDown.Value = 'Default';
            app.PerformPeakFindingButton = uibutton(app.LeftPanel, 'push');
            app.PerformPeakFindingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformPeakFindingButtonPushed, true);
            app.PerformPeakFindingButton.Text = 'Perform Peak Finding';
            app.SplitEditFieldLabel = uilabel(app.LeftPanel);
            app.SplitEditFieldLabel.Text = 'Split';
            app.SplitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SplitEditField.Limits = [0 Inf];
            app.SplitEditField.ValueChangedFcn = createCallbackFcn(app, @SplitEditFieldValueChanged, true);
            app.SplitEditField.Value = 3100000000;
            app.ToleranceEditFieldLabel = uilabel(app.LeftPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 100000000;
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RandomChunksButton = uibutton(app.LeftPanel, 'push');
            app.RandomChunksButton.ButtonPushedFcn = createCallbackFcn(app, @RandomChunksButtonPushed, true);
            app.RandomChunksButton.Text = 'Random Chunks';
            app.ofChunksEditFieldLabel = uilabel(app.LeftPanel);
            app.ofChunksEditFieldLabel.Text = '# of Chunks';
            app.ofChunksEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ofChunksEditField.Limits = [1 256];
            app.ofChunksEditField.RoundFractionalValues = 'on';
            app.ofChunksEditField.Value = 3;
            app.RightButton = uibutton(app.LeftPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = '>>';
            app.LeftButton = uibutton(app.LeftPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<<';
            app.LoadedListBoxLabel = uilabel(app.LeftPanel);
            app.LoadedListBoxLabel.Text = 'Loaded:';
            app.LoadedListBox = uilistbox(app.LeftPanel);
            app.LoadedListBox.Items = {};
            app.LoadedListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedListBoxValueChanged, true);
            app.LoadedListBox.Value = {};
            app.LowerLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.UpperLimitEditFieldLabel = uilabel(app.LeftPanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.MinDistEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDistEditFieldLabel.Text = 'Min. Dist.';
            app.MinDistEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinDistEditField.ValueChangedFcn = createCallbackFcn(app, @MinDistEditFieldValueChanged, true);
            app.MinDistEditField.Value = 0.002;
            app.ThresholdLabel = uilabel(app.LeftPanel);
            app.ThresholdLabel.Text = 'Threshold %';
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 5;
            app.OfPeaksSpinnerLabel = uilabel(app.LeftPanel);
            app.OfPeaksSpinnerLabel.Text = '# Of Peaks';
            app.OfPeaksSpinner = uispinner(app.LeftPanel);
            app.OfPeaksSpinner.Limits = [1 Inf];
            app.OfPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @OfPeaksSpinnerValueChanged, true);
            app.OfPeaksSpinner.Value = 2;
            app.SmthSpanLabel = uilabel(app.LeftPanel);
            app.SmthSpanLabel.Text = 'Smth Span';
            app.SmthSpanSpinner = uispinner(app.LeftPanel);
            app.SmthSpanSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthSpanSpinnerValueChanged, true);
            app.SmthSpanSpinner.Value = 41;
            app.SmthDegreeSpinnerLabel = uilabel(app.LeftPanel);
            app.SmthDegreeSpinnerLabel.Text = 'Smth Degree';
            app.SmthDegreeSpinner = uispinner(app.LeftPanel);
            app.SmthDegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @SmthDegreeSpinnerValueChanged, true);
            app.SmthDegreeSpinner.Value = 7;
            app.SaveCurrentParameterDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveCurrentParameterDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentParameterDataButtonPushed, true);
            app.SaveCurrentParameterDataButton.Text = 'Save Current Parameter Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PeakFindingTab = uitab(app.TabGroup);
            app.PeakFindingTab.Title = 'Peak Finding';
            app.DisplayAxes = uiaxes(app.PeakFindingTab);
            title(app.DisplayAxes, ',')
            xlabel(app.DisplayAxes, 'X')
            ylabel(app.DisplayAxes, 'Y')
            zlabel(app.DisplayAxes, 'Z')
            app.DisplayAxes.ButtonDownFcn = createCallbackFcn(app, @handle, true);
            app.SpectrumAxes = uiaxes(app.PeakFindingTab);
            title(app.SpectrumAxes, 'Signal')
            xlabel(app.SpectrumAxes, 'Frequency')
            ylabel(app.SpectrumAxes, 'Response')
            zlabel(app.SpectrumAxes, 'Z')
            app.PixelDetailsTab = uitab(app.TabGroup);
            app.PixelDetailsTab.Title = 'Pixel Details';
            app.PixelValueAxes = uiaxes(app.PixelDetailsTab);
            title(app.PixelValueAxes, 'Change in Pixel Value')
            xlabel(app.PixelValueAxes, 'X')
            ylabel(app.PixelValueAxes, 'Y')
            zlabel(app.PixelValueAxes, 'Z')
            app.PixelValueTable = uitable(app.PixelDetailsTab);
            app.PixelValueTable.ColumnName = {'Parameter'; 'Value'};
            app.PixelValueTable.RowName = {};
            app.FileDataTable = uitable(app.PixelDetailsTab);
            app.FileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.FileDataTable.RowName = {};
            app.FileDataTable.ColumnEditable = [false false false];
            app.SlopeSuppressionTab = uitab(app.TabGroup);
            app.SlopeSuppressionTab.Title = 'Slope Suppression';
            app.SlopeMapAxes = uiaxes(app.SlopeSuppressionTab);
            title(app.SlopeMapAxes, ',')
            xlabel(app.SlopeMapAxes, 'X')
            ylabel(app.SlopeMapAxes, 'Y')
            zlabel(app.SlopeMapAxes, 'Z')
            app.SlopeMapAxes.ButtonDownFcn = createCallbackFcn(app, @SlopeMapAxesButtonDown, true);
            app.SlopeAxes = uiaxes(app.SlopeSuppressionTab);
            title(app.SlopeAxes, 'Slope')
            xlabel(app.SlopeAxes, 'X')
            ylabel(app.SlopeAxes, 'Value')
            zlabel(app.SlopeAxes, 'Z')
            app.SlopeFileDataTable = uitable(app.SlopeSuppressionTab);
            app.SlopeFileDataTable.ColumnName = {'File'; 'Value'; 'X'};
            app.SlopeFileDataTable.RowName = {};
            app.SlopeFileDataTable.ColumnEditable = [false false true];
            app.SlopeFileDataTable.CellEditCallback = createCallbackFcn(app, @SlopeFileDataTableCellEdit, true);
            app.PlottedValueDropDownLabel = uilabel(app.SlopeSuppressionTab);
            app.PlottedValueDropDownLabel.Text = 'Plotted Value';
            app.PlottedValueDropDown = uidropdown(app.SlopeSuppressionTab);
            app.PlottedValueDropDown.Items = {'Slope', 'R-squared'};
            app.PlottedValueDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottedValueDropDownValueChanged, true);
            app.PlottedValueDropDown.Value = 'Slope';
            app.AlignImagesButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.AlignImagesButton.Enable = 'off';
            app.AlignImagesButton.Text = 'Align Images';
            app.FilestoAlignListBoxLabel = uilabel(app.SlopeSuppressionTab);
            app.FilestoAlignListBoxLabel.Text = 'Files to Align';
            app.FilestoAlignListBox = uilistbox(app.SlopeSuppressionTab);
            app.FilestoAlignListBox.Multiselect = 'on';
            app.FilestoAlignListBox.Value = {'Item 1'};
            app.SavePlotDataButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.SavePlotDataButton.Text = 'Save Plot Data';
            app.CalculateSlopesButton = uibutton(app.SlopeSuppressionTab, 'push');
            app.CalculateSlopesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSlopesButtonPushed, true);
            app.CalculateSlopesButton.Text = 'Calculate Slopes';
            app.FunPlotsTab = uitab(app.TabGroup);
            app.FunPlotsTab.Title = 'Fun Plots';
            app.Axes3D = uiaxes(app.FunPlotsTab);
            title(app.Axes3D, ',')
            xlabel(app.Axes3D, 'X')
            ylabel(app.Axes3D, 'Y')
            zlabel(app.Axes3D, 'Z')
            app.HorizontalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.HorizontalSpectrumAxes, 'Horizontal Signal')
            xlabel(app.HorizontalSpectrumAxes, 'Peak Location')
            ylabel(app.HorizontalSpectrumAxes, 'Row')
            zlabel(app.HorizontalSpectrumAxes, 'Z')
            app.VerticalSpectrumAxes = uiaxes(app.FunPlotsTab);
            title(app.VerticalSpectrumAxes, 'Vertical Signal')
            xlabel(app.VerticalSpectrumAxes, 'Peak Location')
            ylabel(app.VerticalSpectrumAxes, 'Column')
            zlabel(app.VerticalSpectrumAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = global_peaks_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end